classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FACIALFEATUREDETECTIONUSINGMATLABLabel  matlab.ui.control.Label
        DetectMaskedFaceButton          matlab.ui.control.Button
        DetectRockPaperorScissorButton  matlab.ui.control.Button
        BlurscreenincaseofmotionButton  matlab.ui.control.Button
        BlurmyFaceButton                matlab.ui.control.Button
        FlipUpsideDownButton            matlab.ui.control.Button
        FlipLeftToRightButton           matlab.ui.control.Button
        BlurImageButton                 matlab.ui.control.Button
        DetectMouthButton               matlab.ui.control.Button
        DetectEyesButton                matlab.ui.control.Button
        DetectNoseButton                matlab.ui.control.Button
        DetestFaceButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DetestFaceButton
        function DetestFaceButtonPushed(app, event)
            % Reading the input image
            I = imread('img.jpeg');
            
            % Detecting Face
            FaceDetect = vision.CascadeObjectDetector;
            
            %Returning Bounding Box values based on number of objects
            BoundBox = step(FaceDetect,I);
            
            figure,
            imshow(I); 
            hold on
            
            for i = 1:size(BoundBox,1)
                rectangle('Position',BoundBox(i,:),'LineWidth',5,'LineStyle','-','EdgeColor','y');
            end
            title('Face Detection');
            hold off;
        end

        % Button pushed function: DetectNoseButton
        function DetectNoseButtonPushed(app, event)
            % Reading the input image
            I = imread('img.jpeg');
            
            % Detecting Nose
            NoseDetect = vision.CascadeObjectDetector('Nose','MergeThreshold',16);
            
            BoundBox=step(NoseDetect,I);
            
            figure,
            imshow(I); 
            hold on
            
            for i = 1:size(BoundBox,1)
                rectangle('Position',BoundBox(i,:),'LineWidth',4,'LineStyle','-','EdgeColor','y');
            end
            title('Nose Detection');
            hold off;
        end

        % Button pushed function: DetectEyesButton
        function DetectEyesButtonPushed(app, event)
            %% Cascade Object Detector
            Detector = vision.CascadeObjectDetector('EyePairBig');
            Detector.MinSize = [11 45];
            Detector.MergeThreshold = 16;
            
            %%Input Image For Testing
            I = imread('img.jpeg');
            
            %% Detect Human Facial Feature
            bbox = step(Detector,I);
            
            %% Display Detected Facial Feature
            FaceFeature = insertObjectAnnotation(I,'rectangle',bbox,'Detected');
            figure
            imshow(FaceFeature)
            title('Eyes Detected ');
            
        end

        % Button pushed function: BlurImageButton
        function BlurImageButtonPushed(app, event)
            clc
            clear all
            close all
            warning off
            x=imread('cameraman.tif');
            imshow(x);
            h=fspecial('motion');
            k=conv2(x,h);
            figure;
            imshow(uint8(k));
            figure;
            k=conv2(h,x);
            imshow(uint8(k));
        end

        % Button pushed function: FlipLeftToRightButton
        function FlipLeftToRightButtonPushed(app, event)
            clc
            clear all
            close all
            warning off
            x=imread('cameraman.tif');
            subplot(1,2,1);
            imshow(x);
            title('Original Image');
            subplot(1,2,2);
            imshow(fliplr(x));
            title('Flipped Image from lef to to right');
        end

        % Button pushed function: FlipUpsideDownButton
        function FlipUpsideDownButtonPushed(app, event)
            clc
            clear all
            close all
            warning off
            x=imread('cameraman.tif');
            subplot(1,2,1);
            imshow(x);
            title('Original Image');
            subplot(1,2,2);
            imshow(flipud(x));
            title('Flipped Image from up to down');
        end

        % Button pushed function: BlurmyFaceButton
        function BlurmyFaceButtonPushed(app, event)
            clc
            clear all
            close all
            warning off;
            c=webcam;
            faceDetector = vision.CascadeObjectDetector;
            while true
                e=c.snapshot;
                msa=e;
                bboxes =step(faceDetector,e);
                 if(sum(sum(bboxes))~=0)
                     es=imcrop(e,bboxes);
                     a=fspecial('average',[20 20]);
                     ls=imfilter(es,a);
                     e(bboxes(2):bboxes(2)+bboxes(4),bboxes(1):bboxes(1)+bboxes(3),:)=ls;
            imshowpair(msa,e,'montage');
                 es=[];
                 else
                     imshowpair(e,e,'montage');
                 end
                 pause(0.1);
            end
        end

        % Button pushed function: BlurscreenincaseofmotionButton
        function BlurscreenincaseofmotionButtonPushed(app, event)
            clc
            clear all
            close all
            warning off;
            c=webcam;
            faceDetector = vision.CascadeObjectDetector;
            while true
                e=c.snapshot;
                bboxes =step(faceDetector,e);
                 if(sum(sum(bboxes))==0)
                     B = imgaussfilt(e,20);
                       imshow(B);
                 es=[];
                 else
                     imshow(e);
                 end
                 pause(0.1);
            end
        end

        % Button pushed function: DetectRockPaperorScissorButton
        function DetectRockPaperorScissorButtonPushed(app, event)
            clc;
            close all;
            clear;
            workspace;
            
            %Get iformation about the cameras connected to the computer
            vidInfo = imaqhwinfo;
            %Initialize camera object
            vid = videoinput('winvideo', 1);
            %Open preview window
            preview(vid);
            
            %The user can choose to take a picture or load one
            message = sprintf('Would you like to take a picture?');
            reply = questdlg(message, 'Capture Image', 'Yes', 'No', 'Yes');
            if strcmpi(reply, 'Yes')
                %Shoot a picture
                imgFromCam = getsnapshot(vid);
                I = imgFromCam;
            else
                %Delete the camera object
                delete(vid);
                %Load the picture
                [filename, pathname] = ...
                 uigetfile({'*.jpg';'*.png';'*.tif';'*.*'},'Select an Image file');
                fullFileName = fullfile(pathname, filename);
                I = imread(fullFileName);
            end
            
            %Delete the camera object and
            %Close the preview window
            delete(vid);
            
            figure(1)
            subplot(2,2,1)
            imshow(I);
            
            height = size(I,1);
            width = size(I,2);
            
            %We create a new image called O (original) so we can manipulate it later
            O = I;
            %New binary image filled with zeros
            BW = zeros(height,width);
            
            %For the skin detection we convert the image from RGB to YCbCr
            %https://en.wikipedia.org/wiki/YCbCr
            I_ycbcr = rgb2ycbcr(I);
            Cb = I_ycbcr(:,:,2);
            Cr = I_ycbcr(:,:,3);
            
            figure(1)
            subplot(2,2,2)
            imshow(I_ycbcr);
            
            
            %This is where we detect the skin pixels
            [r,c,v] = find(Cb>=77 & Cb<=127 & Cr>=133 & Cr<=173);
            numind = size(r,1);
            
            for i=1:numind
                %On the original image we mark the skin pixels with red color
                O(r(i),c(i),:) = [255 0 0];
                %Fill the binary image with ones where we detect skin pixels
                BW(r(i),c(i)) = 1;
            end
            
            %Fill the little black holes
            BW = imfill(BW, 'holes');
            %Delete small areas on the image
            BW = bwareaopen(BW,900);
            
            se = strel('square',70);
            %The fingers are smaller than the palm so we can "delete" them
            %With simple morphology
            BW2 = imerode(BW, se);
            %Reconstruct the palm (or a little bigger than the original)
            BW2 = imdilate(BW2,se);
            %If we subtract the "only palm" image from the original one we will get the fingers only
            BW3 = imsubtract(BW, BW2);
            %"noise" reduction
            BW3 = bwareaopen(BW3,9000);
            BW = BW3;
            
            figure(1)
            subplot(2,2,3)
            imshow(BW);
            
            %Get the information about the contiguous areas
            st = regionprops(BW, 'All');
            
            fprintf(1,'Area #    Area\n');
            for k = 1 : length(st)
                thisBB = st(k).BoundingBox;
                hold on
                rectangle('Position', [thisBB(1),thisBB(2),thisBB(3),thisBB(4)],...
                  'EdgeColor','r','LineWidth',1 );
                hold off
                fprintf(1,'#%2d %16.1f\n',k,st(k).Area);
            end
            
            %Sort the areas
            allAreas = [st.Area];
            [sortedAreas, sortingIndexes] = sort(allAreas, 'descend');
            
            %Count the areas and label them on the binary image
            for k = 1 : length(st)
                centerX = st(sortingIndexes(k)).Centroid(1);
                centerY = st(sortingIndexes(k)).Centroid(2);
                text(centerX,centerY,num2str(k),'Color', 'b', 'FontSize', 14)
            end
            
            
            %Now we can detect what is on the picture
            if sortingIndexes > 0
                if length(sortingIndexes) >= 3
                    title('PAPER');
                elseif length(sortingIndexes) < 3 && length(sortingIndexes) >= 2
                    title('SCISSORS');
                end
            else
                title('ROCK');
            end
        end

        % Button pushed function: DetectMaskedFaceButton
        function DetectMaskedFaceButtonPushed(app, event)
            g = imread("faces_masked.jpg");
            I = rgb2gray(g);
            faceDetector = vision.CascadeObjectDetector;
            bboxes = step(faceDetector, I);
            IFaces = insertObjectAnnotation(I, 'rectangle', bboxes, 'Face');
            figure
            imshow(IFaces), title('Detected faces');
        end

        % Button pushed function: DetectMouthButton
        function DetectMouthButtonPushed(app, event)
            a = imread("img.jpeg");
            imshow(a);
            
            detector = vision.CascadeObjectDetector('mouth');
            detector.MergeThreshold = 100;
            bbox = step(detector, a);
            out = insertObjectAnnotation(a,'rectangle',bbox,'detection');
            imshow(out)
            title("Mouth Detected")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DetestFaceButton = uibutton(app.UIFigure, 'push');
            app.DetestFaceButton.ButtonPushedFcn = createCallbackFcn(app, @DetestFaceButtonPushed, true);
            app.DetestFaceButton.Text = 'Detest Face';
            app.DetectNoseButton = uibutton(app.UIFigure, 'push');
            app.DetectNoseButton.ButtonPushedFcn = createCallbackFcn(app, @DetectNoseButtonPushed, true);
            app.DetectNoseButton.Text = 'Detect Nose';
            app.DetectEyesButton = uibutton(app.UIFigure, 'push');
            app.DetectEyesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectEyesButtonPushed, true);
            app.DetectEyesButton.Text = 'Detect Eyes';
            app.DetectMouthButton = uibutton(app.UIFigure, 'push');
            app.DetectMouthButton.ButtonPushedFcn = createCallbackFcn(app, @DetectMouthButtonPushed, true);
            app.DetectMouthButton.Text = 'Detect Mouth';
            app.BlurImageButton = uibutton(app.UIFigure, 'push');
            app.BlurImageButton.ButtonPushedFcn = createCallbackFcn(app, @BlurImageButtonPushed, true);
            app.BlurImageButton.Text = 'Blur Image';
            app.FlipLeftToRightButton = uibutton(app.UIFigure, 'push');
            app.FlipLeftToRightButton.ButtonPushedFcn = createCallbackFcn(app, @FlipLeftToRightButtonPushed, true);
            app.FlipLeftToRightButton.Text = 'Flip Left To Right';
            app.FlipUpsideDownButton = uibutton(app.UIFigure, 'push');
            app.FlipUpsideDownButton.ButtonPushedFcn = createCallbackFcn(app, @FlipUpsideDownButtonPushed, true);
            app.FlipUpsideDownButton.Text = 'Flip Upside Down';
            app.BlurmyFaceButton = uibutton(app.UIFigure, 'push');
            app.BlurmyFaceButton.ButtonPushedFcn = createCallbackFcn(app, @BlurmyFaceButtonPushed, true);
            app.BlurmyFaceButton.Text = 'Blur my Face';
            app.BlurscreenincaseofmotionButton = uibutton(app.UIFigure, 'push');
            app.BlurscreenincaseofmotionButton.ButtonPushedFcn = createCallbackFcn(app, @BlurscreenincaseofmotionButtonPushed, true);
            app.BlurscreenincaseofmotionButton.Text = 'Blur screen in case of motion';
            app.DetectRockPaperorScissorButton = uibutton(app.UIFigure, 'push');
            app.DetectRockPaperorScissorButton.ButtonPushedFcn = createCallbackFcn(app, @DetectRockPaperorScissorButtonPushed, true);
            app.DetectRockPaperorScissorButton.Text = 'Detect Rock, Paper or Scissor';
            app.DetectMaskedFaceButton = uibutton(app.UIFigure, 'push');
            app.DetectMaskedFaceButton.ButtonPushedFcn = createCallbackFcn(app, @DetectMaskedFaceButtonPushed, true);
            app.DetectMaskedFaceButton.Text = 'Detect Masked Face';
            app.FACIALFEATUREDETECTIONUSINGMATLABLabel = uilabel(app.UIFigure);
            app.FACIALFEATUREDETECTIONUSINGMATLABLabel.Text = 'FACIAL FEATURE DETECTION USING MATLAB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end